classdef Counter_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        StartButton      matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        TotalCountLabel  matlab.ui.control.Label
        Label            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global counter;

            if isempty(counter)
                counter = 0;
            end

            sim('Counter_condition')


            for i = 1:length(counter_var.Data)

                pause(1);

                switch counter_var.Data(i)
                    %switch randi(2)
                    case 1
                        % increase
                        increament();
                        app.Label.Text = num2str(counter);
                        app.Label_2.Text = 'Increased';
                        app.Label_2.FontColor = [0,1,0];

                    case 2
                        decreament();

                        if counter < 0
                            counter = 0;
                        end

                        app.Label.Text = num2str(counter);
                        app.Label_2.Text = 'Decreased';
                        app.Label_2.FontColor = [1,0,0];

                end

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.TotalCountLabel = uilabel(app.UIFigure);
            app.TotalCountLabel.Text = 'Total Count';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Counter_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end